classdef MainWindow2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image2                      matlab.ui.control.Image
        deFariaCarballoMaidanaetalLabel  matlab.ui.control.Label
        AlloperationcanbeappliedtosubsetsofcellsLabel  matlab.ui.control.Label
        UsealwaysonlyfirstlettercapitalLabel  matlab.ui.control.Label
        VisualizationscalingLabel   matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        ClustersnumberingisdescribedinthepopupwindowandLabel  matlab.ui.control.Label
        ClassesorderEditField       matlab.ui.control.EditField
        ClassesorderEditFieldLabel  matlab.ui.control.Label
        BoxplotspanelLabel          matlab.ui.control.Label
        GenerateGeneViolinButton    matlab.ui.control.Button
        CloseallButton              matlab.ui.control.Button
        AddcurrentviewtoaconcantenatedfigureontopofothersLabel_2  matlab.ui.control.Label
        IntensitycoloringappearanceLabel  matlab.ui.control.Label
        KeepexistingfigureStartanotheranalysisinnewfigurewindowLabel  matlab.ui.control.Label
        choosechannelorratioLabel   matlab.ui.control.Label
        Slider_2                    matlab.ui.control.Slider
        TopSliderLabel              matlab.ui.control.Label
        ManualCheckBox              matlab.ui.control.CheckBox
        ThismeansthatcellswithbottomexpressionwillnotbeshownLabel  matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        BottomSliderLabel           matlab.ui.control.Label
        InLabel                     matlab.ui.control.Label
        SetMenu2                    matlab.ui.container.ButtonGroup
        CampariColor                matlab.ui.control.DropDown
        Label_3                     matlab.ui.control.Label
        SingleNormalization         matlab.ui.control.RadioButton
        Raw_counts                  matlab.ui.control.RadioButton
        AreaButton                  matlab.ui.control.RadioButton
        LouvainButton               matlab.ui.control.RadioButton
        CampariExpressionButton     matlab.ui.control.RadioButton
        ShownegativesCheckBox       matlab.ui.control.CheckBox
        NewfigButton                matlab.ui.control.Button
        MarkerSize                  matlab.ui.control.NumericEditField
        MarkerSizeLabel             matlab.ui.control.Label
        lightest0darkestvaluiesmustdecreaseLabel  matlab.ui.control.Label
        HueEditFieldLabel_3         matlab.ui.control.Label
        HueEditFieldLabel_2         matlab.ui.control.Label
        ValEditField_2              matlab.ui.control.NumericEditField
        SatEditField_2              matlab.ui.control.NumericEditField
        HueEditField_2              matlab.ui.control.NumericEditField
        ValEditField                matlab.ui.control.NumericEditField
        BlueLabel                   matlab.ui.control.Label
        SatEditField                matlab.ui.control.NumericEditField
        GreenLabel                  matlab.ui.control.Label
        HueEditField                matlab.ui.control.NumericEditField
        RedLabel                    matlab.ui.control.Label
        Transparency_2              matlab.ui.control.EditField
        AutoLabel                   matlab.ui.control.Label
        AutoscaleCheckBox           matlab.ui.control.CheckBox
        RimCheckBox                 matlab.ui.control.CheckBox
        Heatmap                     matlab.ui.control.DropDown
        CampariChoice               matlab.ui.control.DropDown
        BottomGEEditField           matlab.ui.control.NumericEditField
        BottomGEEditFieldLabel      matlab.ui.control.Label
        TopGEEditField              matlab.ui.control.NumericEditField
        TopGEEditFieldLabel         matlab.ui.control.Label
        GeneEditField               matlab.ui.control.EditField
        GeneLabel                   matlab.ui.control.Label
        GOButton                    matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function results = triggerGeneEditFieldValueChanged(app, newValue)
            if nargin > 1
            prev = app.GeneEditField.Value;
            app.GeneEditField.Value = newValue;
            evt = struct('Value', newValue, 'PreviousValue', prev);
        else
            evt = struct('Value', app.GeneEditField.Value, 'PreviousValue', []);
        end

        % Call the original private callback
        GeneEditFieldValueChanged(app, evt);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CampariChoice, CampariColor, GOButton, 
        % ...and 9 other components
        function GeneEditFieldValueChanged(app, event)
            if ~app.SetMenu2.Children(6).Value
                if strcmp(app.Heatmap.Value,'Louvain_vis')
                app.Heatmap.Value='Jet';
                end
            end
            create_colored_scatter_plot(app);
        end

        % Button pushed function: NewfigButton
        function NewfigButtonPushed(app, event)
            figure;
            H=axes;
            F=H.Parent;
            % value = app.FigurescalingEditField.Value;
            % F.Position(3)= F.Position(3)*value;
            % F.Position(4)= F.Position(4)*value;
            T1=scatter(H,app.UIFigure.UserData.umap(:,1),app.UIFigure.UserData.umap(:,2),'filled');
            hold(H,'on');
            T2=scatter(H,app.UIFigure.UserData.umap(:,1),app.UIFigure.UserData.umap(:,2),'filled');
            T2.CData=[0.9 0.9 0.9];
            HM=axes;
            HM.Position=[H.Position(1) H.Position(2)-H.Position(4)*0.1-0.01 0.5*H.Position(3) H.Position(4)*0.05];
            H.XTickLabels=[];
            H.YTickLabels=[];
            HM.YTickLabels=[];
            HM.XTickLabels=[];
            HMLLim = annotation("textbox");
            HMLLim.Position=[HM.Position(1)-0.11*H.Position(3) HM.Position(2) 0.1*H.Position(3) H.Position(4)*0.05];
            HMRLim = annotation("textbox");
            HMRLim.Position=[HM.Position(1)+HM.Position(3)+0.01 HM.Position(2) 0.1*H.Position(3) H.Position(4)*0.05];
            HMLLim.LineStyle='none';
            HMRLim.LineStyle='none';
            HMinfo = annotation("textbox");
            HMinfo.Position=[0 0.7 0.2 0.3];
            HMinfo.LineStyle='none';
            %AppHandle.UserData.Handles=[F H T1 T2 HM HMLLim HMRLim HMinfo];
            app.UIFigure.UserData.Handles=[F H T1 T2 HM HMLLim HMRLim HMinfo];
            axesObjs = findall(F, 'type', 'axes');    % find all axes in figure F
            set(axesObjs, 'Visible', 'off');          % hide axes, ticks, labels, etc.
        end

        % Value changed function: RimCheckBox
        function RimCheckBoxValueChanged(app, event)
            value = app.RimCheckBox.Value;
            T1=app.UIFigure.UserData.Handles(3);
            T2=app.UIFigure.UserData.Handles(4);
            T1.MarkerEdgeColor='flat';
            if value==1
                T1.MarkerFaceAlpha=0;
                T1.MarkerEdgeAlpha=1;
            else
                T1.MarkerFaceAlpha=1;
                T1.MarkerEdgeAlpha=0;
            end
        end

        % Value changed function: ShownegativesCheckBox
        function ShownegativesCheckBoxValueChanged(app, event)
            value = app.ShownegativesCheckBox.Value;
            create_colored_scatter_plot(app);
        end

        % Callback function
        function AddcurrentButtonPushed(app, event)
            R=gcf;
            if isempty(app.AddcurrentButton.UserData)
                figure;
                H=axes;
                axis off
                F=H.Parent;
                value = app.FigurescalingEditField.Value;
                F=gcf;
                F.Position(3)= F.Position(3)*value;
                F.Position(4)= F.Position(4)*value;
                F.Color=[1 1 1];
                app.AddcurrentButton.UserData.Handles=[F H];
            elseif ~isvalid(app.AddcurrentButton.UserData.Handles(1));
                figure;
                H=axes;
                axis off
                F=H.Parent;
                F.Color=[1 1 1];
                app.AddcurrentButton.UserData.Handles=[F H];
            end
                hold on
                N1=scatter(app.AddcurrentButton.UserData.Handles(2),1,1);
                hold on 
                T1=app.UIFigure.UserData.Handles(3);
                N1.Marker=T1.Marker;
                N1.MarkerEdgeColor=T1.MarkerEdgeColor;
                N1.MarkerFaceColor=T1.MarkerFaceColor;
                N1.SizeData=T1.SizeData;
                N1.LineWidth=T1.LineWidth;
                N1.XData=T1.XData;
                N1.YData=T1.YData;
                N1.ZData=T1.ZData;
                N1.CData=T1.CData;
                N1.MarkerEdgeAlpha=T1.MarkerEdgeAlpha;
                N1.MarkerFaceAlpha=T1.MarkerFaceAlpha;
                N1.Visible=T1.Visible;
                hold on
                N2=scatter(app.AddcurrentButton.UserData.Handles(2),1,1);
                hold on 
                T2=app.UIFigure.UserData.Handles(4);
                N2.Marker=T2.Marker;
                N2.MarkerEdgeColor=T2.MarkerEdgeColor;
                N2.MarkerFaceColor=T2.MarkerFaceColor;
                N2.SizeData=T2.SizeData;
                N2.LineWidth=T2.LineWidth;
                N2.XData=T2.XData;
                N2.YData=T2.YData;
                N2.ZData=T2.ZData;
                N2.CData=T2.CData;
                N2.MarkerEdgeAlpha=T2.MarkerEdgeAlpha;
                N2.MarkerFaceAlpha=T2.MarkerFaceAlpha;
                N2.Visible=T2.Visible;
                app.AddcurrentButton.UserData.Handles=[app.AddcurrentButton.UserData.Handles N2];
                app.AddcurrentButton.UserData.Handles=[app.AddcurrentButton.UserData.Handles N1];
                figure(R);
                
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
            value = app.ManualCheckBox.Value;
            app.AutoscaleCheckBox.Value=~app.AutoscaleCheckBox.Value;
            create_colored_scatter_plot(app);
        end

        % Value changed function: AutoscaleCheckBox
        function AutoscaleCheckBoxValueChanged(app, event)
            value = app.AutoscaleCheckBox.Value;
            app.ManualCheckBox.Value=~app.ManualCheckBox.Value;
            create_colored_scatter_plot(app);
        end

        % Value changed function: BottomGEEditField
        function BottomGEEditFieldValueChanged(app, event)
            value = app.BottomGEEditField.Value;
            err=val_test(app);
            if err==1;
                return;
            end
            app.Slider.Value=value;
            create_colored_scatter_plot(app);
        end

        % Value changed function: TopGEEditField
        function TopGEEditFieldValueChanged(app, event)
            value = app.TopGEEditField.Value;
            err=val_test(app);
            if err==1;
                return;
            end
            if value>app.Slider_2.Limits(2);
            slider_adjust(app,value);   
            end
            app.Slider_2.Value=value;
            create_colored_scatter_plot(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.BottomGEEditField.Value=value;
            err=val_test(app);
            if err==1;
                return;
            end
            create_colored_scatter_plot(app);
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.TopGEEditField.Value=value;
            err=val_test(app);
            if err==1;
                return;
            end
            create_colored_scatter_plot(app);
        end

        % Callback function
        function CampariColorValueChanged(app, event)
            value = app.CampariColor.Value;
            
        end

        % Callback function
        function FigurescalingEditFieldValueChanged(app, event)
            value = app.FigurescalingEditField.Value;
            F=gcf;
            F.Position(3)= F.Position(3)*value;
            F.Position(4)= F.Position(4)*value;
        end

        % Callback function
        function RunformattedlistButtonPushed(app, event)
            answ = inputdlg('paste string of genes','LOAD LIST',10);
            handles=[];
            if isempty(strfind(answ{1},'['));
                answ{1}=strcat('[',answ{1});
            end
            if isempty(strfind(answ{1},']'));
                answ{1}=strcat(answ{1},']');
            end
            answ{1} = strrep(answ{1},'[','{');
            answ{1} = strrep(answ{1},']','}');
            A=eval(answ{1});
            for i=1:size(A,2)
                app.GeneEditField.Value=A{i};
                create_colored_scatter_plot(app);
                NewfigButtonPushed(app, event);
                handles(i)=gcf;
            end
            spreadfigures(handles,'square');
        end

        % Callback function
        function Button_2Pushed(app, event)
            h = findobj('Type','figure');
            cr=gcf;
            for j=1:size(h,2);
                if h(j)==cr;
                    pos=j;
                end
            end
            h=circshift(h,-1);
            figure(h(pos));    
        end

        % Callback function
        function ButtonPushed(app, event)
            h = findobj('Type','figure');
            cr=gcf;
            for j=1:size(h,2)
                if h(j)==cr;
                    pos=j;
                end
            end
            h=circshift(h,1);
            figure(h(pos));   
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all;
        end

        % Callback function
        function Pos_selectionButtonPushed(app, event)
            if isempty(app.Pos_selection.UserData)
               app.Pos_selection.UserData=drawfreehand; 
            else
               H=app.Pos_selection.UserData;
               delete(H);
               app.Pos_selection.UserData=drawfreehand; 
            end
            app.Pos_selection.UserData.Color=[0 1 0];
        end

        % Callback function
        function Produce_enButtonPushed(app, event)
            H=app.GeneEditField.Parent;
            umap=H.UserData.umap;
            pos_cells=inROI(app.Pos_selection.UserData,double(umap(:,1)),double(umap(:,2)));
            if isempty(app.Neg_selection.UserData)
                neg_cells=~pos_cells;
            else 
                neg_cells=inROI(app.Neg_selection.UserData,double(umap(:,1)),double(umap(:,2)));
            end
            % very crude comparison of gene expression between classes can
            % be potentially updated or fixed
            G_names=H.UserData.gene_name;
            Counts=H.UserData.sparse_matrix;
            del=[];
            for j=1:size(G_names,1)
                if nnz(Counts(pos_cells,j))<app.Exprcellfrthr.Value*sum(pos_cells)
                    del=[del j];
                end
            end
            G_names(del,:)=[];
            Counts(:,del)=[];
            neg_counts_norm=double(sum(Counts(neg_cells,:),1)/sum(neg_cells));
            pos_counts_norm=double(sum(Counts(pos_cells,:),1)/sum(pos_cells));
            enrichment= pos_counts_norm./neg_counts_norm;
            [A,i]=sort(enrichment);
            List_names=i(end-app.NumberoftopenrichedgenesEditField.Value:end);
            List_enrichments=A(end-app.NumberoftopenrichedgenesEditField.Value:end);
            Enrichment_table=table(flip(G_names(List_names,:)),flip(List_enrichments'));
           % Enrichment_table.Properties.VariableNames=['Gene','Enrichment fold']
           Dat=datestr(datetime,'dd_mm_yyyy_HHMM')
           fid = fopen(strcat('enrichmentMS_',Dat,'.txt'));
            writetable(Enrichment_table,strcat('enrichmentMS_',Dat,'.txt'));
        end

        % Callback function
        function Neg_selectionButtonPushed(app, event)
            if isempty(app.Neg_selection.UserData)
               app.Neg_selection.UserData=drawfreehand; 
            else
               H=app.Neg_selection.UserData;
               delete(H);
               app.Neg_selection.UserData=drawfreehand; 
            end
            app.Neg_selection.UserData.Color=[1 0 0];
        end

        % Callback function
        function ResetareasButtonPushed(app, event)
            H=app.Neg_selection.UserData;
            delete(H);
            H=app.Pos_selection.UserData;
            delete(H);
        end

        % Callback function
        function ValuesfromselectionexportButtonPushed(app, event)
            H=app.GeneEditField.Parent;
            umap=H.UserData.umap;
            pos_cells=inROI(app.Pos_selection.UserData,double(umap(:,1)),double(umap(:,2)));
            Counts=H.UserData.sparse_matrix;
            for i=1:size(H.UserData.gene_name,1);
            Gene_string(i)=convertCharsToStrings(H.UserData.gene_name(i,1:size(H.UserData.gene_name,2)));
            end
            t1 = strfind(Gene_string,app.GeneEditField.Value);
            loc=find(~cellfun(@isempty,t1));
            SelExpression=Counts(pos_cells,loc);
            Selection_Violin=table(SelExpression);
            writetable(Selection_Violin,'sel_violinMS.txt');
            
        end

        % Callback function
        function LouvainChoiceValueChanged(app, event)
            value = app.LouvainChoice.Value;
            create_colored_scatter_plot(app);
        end

        % Button pushed function: GenerateGeneViolinButton
        function GenerateGeneViolinButtonPushed(app, event)
            UnpackedD=app.UIFigure.UserData;
            % Example string in app.campariChoice.Value
            % Extract the first two characters
            V=app.CampariChoice;
            firstTwoChars = V.Value(1:min(2, length(app.CampariChoice.Value)));
            % Check if the first character is a number
            if isstrprop(firstTwoChars(1), 'digit')
                % Check if the second character is also a number
                if length(firstTwoChars) > 1 && isstrprop(firstTwoChars(2), 'digit')
                    % Both characters are digits, extract the two-digit number
                    stim_number = str2double(firstTwoChars(1:2));
                else
                    % Only the first character is a digit, extract the single-digit number
                    stim_number = str2double(firstTwoChars(1));
                end
                stim_name=app.CampariChoice.Value;
            else
                stim_number=unique(UnpackedD.stimuli);
                stim_name='all';
            end
            
            % Remove any brackets if they exist
            cleanString = regexprep(app.ClassesorderEditField.Value, '[\\[\\]]', '');

            % Convert the cleaned string into a numeric vector
            plotOrder = str2num(cleanString); %#ok<ST2NM> % Parses the string into a numeric array

            if size(UnpackedD.louvain, 1) > 1 && size(UnpackedD.louvain, 2) > 1
                % Extract the column number based on the current value of app.LouvainChoice
                columnNumber = app.LouvainChoice.ValueIndex; % Assuming app.LouvainChoice is a numeric value
                
                % Check if the columnNumber is within bounds
                if columnNumber >= 1 && columnNumber <= size(UnpackedD.louvain, 2)
                    % Select the specified column as the new UnpackedD.louvain
                    UnpackedD.louvain = UnpackedD.louvain(:, columnNumber);
                else
                    error('The specified column number is out of bounds.');
                end
            else
                disp('UnpackedD.louvain does not have size greater than one in both dimensions.');
            end

           plotGeneExpression(UnpackedD.stimuli, stim_number, UnpackedD.normed_matrix, UnpackedD.sparse_matrix, UnpackedD.louvain, UnpackedD.gene_name, [0 4], 4,...
               stim_name, plotOrder, app.GeneEditField.Value,app);
        end

        % Callback function
        function GenerateCountsGeneViolinButtonPushed(app, event)
            UnpackedD=app.UIFigure.UserData;
            V=app.CampariChoice;
            firstTwoChars = V.Value(1:min(2, length(app.CampariChoice.Value)));
            % Check if the first character is a number
            if isstrprop(firstTwoChars(1), 'digit')
                % Check if the second character is also a number
                if length(firstTwoChars) > 1 && isstrprop(firstTwoChars(2), 'digit')
                    % Both characters are digits, extract the two-digit number
                    stim_number = str2double(firstTwoChars(1:2));
                else
                    % Only the first character is a digit, extract the single-digit number
                    stim_number = str2double(firstTwoChars(1));
                end
                stim_name=app.CampariChoice.Value;
            else
                stim_number=unique(UnpackedD.stimuli);
                stim_name='all';
            end
                       % Remove any brackets if they exist
            cleanString = regexprep(app.ClassesorderEditField.Value, '[\\[\\]]', '');

            % Convert the cleaned string into a numeric vector
            plotOrder = str2num(cleanString); %#ok<ST2NM> % Parses the string into a numeric array

            plotGeneCategory(UnpackedD.stimuli, stim_number, UnpackedD.n_genes, UnpackedD.total_counts,UnpackedD.louvain, [app.DownEditField.Value app.UpEditField.Value], app.DotSizeEditField.Value, stim_name, plotOrder,app);
        end

        % Selection changed function: SetMenu2
        function SetMenu2SelectionChanged(app, event)
            selectedButton = app.SetMenu2.SelectedObject;
            if app.SetMenu2.Children(6).Value==1
                app.Heatmap.Value='Louvain_vis';
                app.ManualCheckBox.Value=1;
                app.AutoscaleCheckBox.Value=0;
                app.BottomGEEditField.Value=0;
                app.TopGEEditField.Value=32;
            else
                app.ManualCheckBox.Value=0;
                app.AutoscaleCheckBox.Value=1;
                app.Heatmap.Value='Jet';
            end
            GeneEditFieldValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.GOButton.Text = 'GO!';
            app.GeneLabel = uilabel(app.UIFigure);
            app.GeneLabel.Text = 'Gene...';
            app.GeneEditField = uieditfield(app.UIFigure, 'text');
            app.GeneEditField.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.GeneEditField.Value = 'Avil';
            app.TopGEEditFieldLabel = uilabel(app.UIFigure);
            app.TopGEEditFieldLabel.Text = 'Top GE';
            app.TopGEEditField = uieditfield(app.UIFigure, 'numeric');
            app.TopGEEditField.ValueChangedFcn = createCallbackFcn(app, @TopGEEditFieldValueChanged, true);
            app.TopGEEditField.Value = 32;
            app.BottomGEEditFieldLabel = uilabel(app.UIFigure);
            app.BottomGEEditFieldLabel.Text = 'Bottom GE';
            app.BottomGEEditField = uieditfield(app.UIFigure, 'numeric');
            app.BottomGEEditField.ValueChangedFcn = createCallbackFcn(app, @BottomGEEditFieldValueChanged, true);
            app.CampariChoice = uidropdown(app.UIFigure);
            app.CampariChoice.Items = {'''X: All Cells'', ''0:control'',''1: pinch'',''2: new_stroke (after december)'',''3: ballon'',''4: AG (ano-genital brush)'',''5: AD (anal distension)'',''6: heating'', ...', '''7: mock'',''8: old_stoke (first experiments with stroke)'',''9: anal pinch'',''10: mock with poop'',''11: vaginal distansion'',''12: tomatoe'',...', '''13: poop'',''14: Bladder'',''15: Colon-rectum distension (CRD)'',''16: AD + CFA (anal distension + inflamation)'',''17: AGB + CFA (ano-genital brush + inflamation)'',...', '''18: Heating + CFA (heating + inflamation)'',''19: Tail Brush'',''20: TRPM8 td Tomato'',''21: AD + Pulse'',''22: AGB-CFA 1 W'',''23: AD-CFA 1W'',''24: HP'',''25: Penis'',...', '''26: Clitoris Vibration'',''27: Max Cells'',''28: Tracing Ano Genital Skin injection'',''29: Crazy Mock'',''30: Tracing Anal injection'',''31: Tracing Vagina'',...', '''32: Brush Blink test'',''33: Tracing colon'',''34: Tracing bladder'',''35: Tracing bladder / colon'',''36: NEFH cells'',''37: AGB+CFA TRPV1:Cre baseline'',''38: AGB+CFA TRPV1:Cre(2days)'',''39: AGB+CFA TRPV1:Cre(4days)'',''40: AGB+CFA TRPV1:Cre(6days)'',''41: AGB+CFA TRPV1:Cre(8days)'',...', '''42: Campari green'',''43: Th:Cre neurons'',''44: Excluded AGB'',''100:Random'',''-1: Humans'''};
            app.CampariChoice.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.CampariChoice.Tag = 'StimuliList';
            app.CampariChoice.Value = '''X: All Cells'', ''0:control'',''1: pinch'',''2: new_stroke (after december)'',''3: ballon'',''4: AG (ano-genital brush)'',''5: AD (anal distension)'',''6: heating'', ...';
            app.Heatmap = uidropdown(app.UIFigure);
            app.Heatmap.Items = {'Parula', 'Jet', 'Hsv', 'Hot', 'RGB', 'Lines', 'Colorcube', 'Prism', 'Louvain_vis'};
            app.Heatmap.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.Heatmap.Value = 'Louvain_vis';
            app.RimCheckBox = uicheckbox(app.UIFigure);
            app.RimCheckBox.ValueChangedFcn = createCallbackFcn(app, @RimCheckBoxValueChanged, true);
            app.RimCheckBox.Text = 'Rim';
            app.AutoscaleCheckBox = uicheckbox(app.UIFigure);
            app.AutoscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoscaleCheckBoxValueChanged, true);
            app.AutoscaleCheckBox.Text = 'Autoscale';
            app.AutoLabel = uilabel(app.UIFigure);
            app.AutoLabel.Text = 'Auto %';
            app.Transparency_2 = uieditfield(app.UIFigure, 'text');
            app.Transparency_2.Value = '5';
            app.RedLabel = uilabel(app.UIFigure);
            app.RedLabel.Text = 'Red';
            app.HueEditField = uieditfield(app.UIFigure, 'numeric');
            app.HueEditField.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.HueEditField.Value = 0.9;
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Text = 'Green';
            app.SatEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatEditField.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.BlueLabel = uilabel(app.UIFigure);
            app.BlueLabel.Text = 'Blue';
            app.ValEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValEditField.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.HueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HueEditField_2.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.SatEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SatEditField_2.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.ValEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ValEditField_2.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.HueEditFieldLabel_2 = uilabel(app.UIFigure);
            app.HueEditFieldLabel_2.Text = 'Top';
            app.HueEditFieldLabel_3 = uilabel(app.UIFigure);
            app.HueEditFieldLabel_3.Text = 'Bottom';
            app.lightest0darkestvaluiesmustdecreaseLabel = uilabel(app.UIFigure);
            app.lightest0darkestvaluiesmustdecreaseLabel.Text = {'Reversed '; '1 - lightest intensity'; '0 - darkest - i.e full'; ''};
            app.MarkerSizeLabel = uilabel(app.UIFigure);
            app.MarkerSizeLabel.Text = 'Marker Size';
            app.MarkerSize = uieditfield(app.UIFigure, 'numeric');
            app.MarkerSize.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.MarkerSize.Value = 12;
            app.NewfigButton = uibutton(app.UIFigure, 'push');
            app.NewfigButton.ButtonPushedFcn = createCallbackFcn(app, @NewfigButtonPushed, true);
            app.NewfigButton.Text = 'New figure';
            app.ShownegativesCheckBox = uicheckbox(app.UIFigure);
            app.ShownegativesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShownegativesCheckBoxValueChanged, true);
            app.ShownegativesCheckBox.Text = 'Show negatives';
            app.ShownegativesCheckBox.Value = true;
            app.SetMenu2 = uibuttongroup(app.UIFigure);
            app.SetMenu2.SelectionChangedFcn = createCallbackFcn(app, @SetMenu2SelectionChanged, true);
            app.SetMenu2.Title = 'You are exploring...';
            app.CampariExpressionButton = uiradiobutton(app.SetMenu2);
            app.CampariExpressionButton.Text = 'Campari fluor. levels';
            app.LouvainButton = uiradiobutton(app.SetMenu2);
            app.LouvainButton.Text = 'Louvain clusters';
            app.LouvainButton.Value = true;
            app.AreaButton = uiradiobutton(app.SetMenu2);
            app.AreaButton.Text = 'Area';
            app.Raw_counts = uiradiobutton(app.SetMenu2);
            app.Raw_counts.Text = 'Raw Gene counts';
            app.SingleNormalization = uiradiobutton(app.SetMenu2);
            app.SingleNormalization.Text = 'Normalized expression';
            app.Label_3 = uilabel(app.SetMenu2);
            app.Label_3.Text = '';
            app.CampariColor = uidropdown(app.SetMenu2);
            app.CampariColor.Items = {'Red', 'Green'};
            app.CampariColor.ValueChangedFcn = createCallbackFcn(app, @GeneEditFieldValueChanged, true);
            app.CampariColor.Value = 'Red';
            app.InLabel = uilabel(app.UIFigure);
            app.InLabel.Text = 'In...';
            app.BottomSliderLabel = uilabel(app.UIFigure);
            app.BottomSliderLabel.Text = 'Bottom';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ThismeansthatcellswithbottomexpressionwillnotbeshownLabel = uilabel(app.UIFigure);
            app.ThismeansthatcellswithbottomexpressionwillnotbeshownLabel.Text = {'Autoscale: cells with X% lowest expression '; 'will not be shown, and cells with X% highest expression'; 'will saturate color map. Use small values (e.g. 0.01%) to visualize whole dynamic range. All scaling values are ignroed in Louvain clusters viewing mode.'};
            app.ManualCheckBox = uicheckbox(app.UIFigure);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = 'Manual';
            app.ManualCheckBox.Value = true;
            app.TopSliderLabel = uilabel(app.UIFigure);
            app.TopSliderLabel.Text = 'Top';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.choosechannelorratioLabel = uilabel(app.UIFigure);
            app.choosechannelorratioLabel.Text = 'choose channel or ratio';
            app.KeepexistingfigureStartanotheranalysisinnewfigurewindowLabel = uilabel(app.UIFigure);
            app.KeepexistingfigureStartanotheranalysisinnewfigurewindowLabel.Text = {'Keep existing figure'; 'Start another analysis'; 'in new figure window.'};
            app.IntensitycoloringappearanceLabel = uilabel(app.UIFigure);
            app.IntensitycoloringappearanceLabel.Text = 'Appearance';
            app.AddcurrentviewtoaconcantenatedfigureontopofothersLabel_2 = uilabel(app.UIFigure);
            app.AddcurrentviewtoaconcantenatedfigureontopofothersLabel_2.Text = 'Heatmap type,  default ''jet'' . RGB Can be deifned below. Louvain_vis should be only used with clustering.';
            app.CloseallButton = uibutton(app.UIFigure, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.GenerateGeneViolinButton = uibutton(app.UIFigure, 'push');
            app.GenerateGeneViolinButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGeneViolinButtonPushed, true);
            app.GenerateGeneViolinButton.Text = {'Graph gene expression'; 'per class'};
            app.BoxplotspanelLabel = uilabel(app.UIFigure);
            app.BoxplotspanelLabel.Text = 'Box plots panel';
            app.ClassesorderEditFieldLabel = uilabel(app.UIFigure);
            app.ClassesorderEditFieldLabel.Text = 'Box plot graphs expression and raw counts of the current gene in selected sets of cells across Louvain Clustering defined classes';
            app.ClassesorderEditField = uieditfield(app.UIFigure, 'text');
            app.ClassesorderEditField.Value = '[18 2 13 10 8 0 1 5 11 16 9 7 4]';
            app.ClustersnumberingisdescribedinthepopupwindowandLabel = uilabel(app.UIFigure);
            app.ClustersnumberingisdescribedinthepopupwindowandLabel.Text = 'Clusters numbering is described in the popup window and the coloring matches the one showed by louvain clustering as long as the scaling is set from 0 minimum to 32 maximum which is the default.';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo pref.png');
            app.VisualizationscalingLabel = uilabel(app.UIFigure);
            app.VisualizationscalingLabel.Text = 'Visualization scaling';
            app.UsealwaysonlyfirstlettercapitalLabel = uilabel(app.UIFigure);
            app.UsealwaysonlyfirstlettercapitalLabel.Text = 'Use always only first letter capital.';
            app.AlloperationcanbeappliedtosubsetsofcellsLabel = uilabel(app.UIFigure);
            app.AlloperationcanbeappliedtosubsetsofcellsLabel.Text = 'All operation can be applied to subsets of cells.';
            app.deFariaCarballoMaidanaetalLabel = uilabel(app.UIFigure);
            app.deFariaCarballoMaidanaetalLabel.Text = 'de-Faria, Carballo, Maidana et al.';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'ListClasses.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainWindow2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end